classdef VVCM < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ForwardKinematicsofMultiRobotSystemwithaDeformableSheetLabel  matlab.ui.control.Label
        ResetEditField                  matlab.ui.control.Button
        ForwardKinematicsCalculationButton  matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        TautCableGroupEditField         matlab.ui.control.EditField
        TautCableGroupEditFieldLabel    matlab.ui.control.Label
        ContactPointPositionEditField   matlab.ui.control.EditField
        ContactPointPositionEditFieldLabel  matlab.ui.control.Label
        ObjectPositionEditField         matlab.ui.control.EditField
        ObjectPositionEditFieldLabel    matlab.ui.control.Label
        k5EditField_2                   matlab.ui.control.EditField
        k5EditField_2Label              matlab.ui.control.Label
        k5EditField                     matlab.ui.control.EditField
        k5EditFieldLabel                matlab.ui.control.Label
        k4EditField                     matlab.ui.control.EditField
        k4EditFieldLabel                matlab.ui.control.Label
        k3EditField                     matlab.ui.control.EditField
        k3EditFieldLabel                matlab.ui.control.Label
        ForwardKinematicsNumberEditField  matlab.ui.control.EditField
        ForwardKinematicsNumberEditFieldLabel  matlab.ui.control.Label
        InputPanel                      matlab.ui.container.Panel
        RobotFormationEditField         matlab.ui.control.EditField
        SheetVerticesEditField          matlab.ui.control.EditField
        HeightofHoldingPointsEditField  matlab.ui.control.EditField
        HeightofHoldingPointsEditFieldLabel  matlab.ui.control.Label
        RobotNumberEditField            matlab.ui.control.EditField
        RobotNumberEditFieldLabel       matlab.ui.control.Label
        RobotFormationSelectionButton   matlab.ui.control.Button
        SheetVerticesSelectionButton    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RobotNumberEditField
        function RobotNumberEditFieldValueChanged(comp, event)
            global  RobotNumber 
            RobotNumber = str2double(comp.RobotNumberEditField.Value);
            
        end

        % Button pushed function: SheetVerticesSelectionButton
        function SheetVerticesSelectionButtonPushed(comp, event)
            global  RobotNumber Vn 
            RobotNumber = str2double(comp.RobotNumberEditField.Value);
            Vn = zeros(RobotNumber,2);

            N = RobotNumber;
            
            button = questdlg('Please select the vertices of the sheet in counterclockwise order.','Sheet Veitices Selection','Select','Cancel','Select')  %创建一个指定提问内容和窗口标题的提问
            questAnswer = ('Select');
            Vn_str = [''];
            if  button == questAnswer
                figure(1);
   
                axis([0,1000,0,1000]);hold on;
                     for i=1: RobotNumber
                         Vn(i,:) =  ginput(1);
                         Vn_temp = [num2str(Vn(i,1)),',', num2str(Vn(i,2))];
                         Vn_str = [Vn_str, Vn_temp, ';'];           
                         plot(Vn(i,1),Vn(i,2),'ro');hold on;
                     end
                     
                     comp.SheetVerticesEditField.Value = num2str(Vn_str);
                     %plot in the UIAxes
                     plot(comp.UIAxes,Vn(:,1),Vn(:,2),'ro');hold on;   
                    for i= 1: N-1
                        line(comp.UIAxes,[Vn(i,1) Vn(i+1,1)],[Vn(i,2) Vn(i+1,2)],'Marker','o','color','r');hold on;
                    end
                        line(comp.UIAxes,[Vn(N,1) Vn(1,1)],[Vn(N,2) Vn(1,2)],'Marker','o','color','r');hold on;

                        close(figure(1));
              else

              end

        end

        % Button pushed function: RobotFormationSelectionButton
        function RobotFormationSelectionButtonPushed(comp, event)
            global  RobotNumber Rn Vn 
            RobotNumber = str2double(comp.RobotNumberEditField.Value);
            Rn = zeros(RobotNumber,2);

            N = RobotNumber;
            
            button = questdlg('Please select the robot formation in counterclockwise order.','Robot Formation Selection','Select','Cancel','Select')  %创建一个指定提问内容和窗口标题的提问
            questAnswer = ('Select');
            Rn_str = [''];
            if  button == questAnswer
                figure(1);
                axis([0,1000,0,1000]);hold on;
                plot(Vn(:,1),Vn(:,2),'ro');hold on; 
                for i= 1: N-1
                    line([Vn(i,1) Vn(i+1,1)],[Vn(i,2) Vn(i+1,2)],'Marker','o','color','r');hold on;
                end
                    line([Vn(N,1) Vn(1,1)],[Vn(N,2) Vn(1,2)],'Marker','o','color','r');hold on;

                     for i=1: RobotNumber
                         Rn(i,:) =  ginput(1);
                         Rn_temp = [num2str(Rn(i,1)),',', num2str(Rn(i,2))];
                         Rn_str = [Rn_str, Rn_temp, ';'];           
                         plot(Rn(i,1),Rn(i,2),'bo');hold on;
                     end
                     
                     
                     comp.RobotFormationEditField.Value = num2str(Rn_str);        
                     plot(comp.UIAxes,Rn(:,1),Rn(:,2),'bo');hold on; 

                    for i= 1: N-1
                        line(comp.UIAxes,[Rn(i,1) Rn(i+1,1)],[Rn(i,2) Rn(i+1,2)],'Marker','o','color','b');hold on;
                    end
                        line(comp.UIAxes,[Rn(N,1) Rn(1,1)],[Rn(N,2) Rn(1,2)],'Marker','o','color','b');hold on;
                                 
                    close(figure(1));             
            end
        end

        % Button pushed function: ForwardKinematicsCalculationButton
        function ForwardKinematicsCalculationButtonPushed(comp, event)
            
            global  RobotNumber RobotHeight Rn Vn Pic_num
            RobotNumber = str2double(comp.RobotNumberEditField.Value);
            RobotHeight = str2double(comp.HeightofHoldingPointsEditField.Value);

            N = RobotNumber;
             zr = RobotHeight;

            %Forward Kinematics Calculation
            [M, Po,Vo,ID,Tn,ispossible1] = VVCM_FK(zr,Vn,Rn);

            M_polygon       = 0;
            M_pentagon      = 0;
            M_quadrilateral = 0;
            M_triangle      = 0;
        
        
            for i=1:M
                if Tn(i) == 3
                    M_triangle = M_triangle +1;
                end
                if Tn(i) == 4
                    M_quadrilateral = M_quadrilateral +1;
                end
                if Tn(i) == 5
                    M_pentagon = M_pentagon +1;
                end
                if Tn(i) > 5
                    M_polygon = M_polygon +1;
                end  
            end

            if ispossible1
    
             %Output Po Vo ID
             Pic_num = M;
             Vo_str = [''];
             Po_str = [''];
             ID_str = [''];
             for i=1: M
                 Vo_temp = [num2str(Vo(i,1)),',', num2str(Vo(i,2))];
                 Vo_str = [Vo_str, Vo_temp, ';'];           

                 Po_temp = [num2str(Po(i,1)),',', num2str(Po(i,2)),',', num2str(Po(i,3))];
                 Po_str = [Po_str, Po_temp, ';']; 
                    
                 ID_temp = [num2str(ID(i,1))];
                 for j=2:N
                     ID_temp = [ID_temp,',', num2str(ID(i,j))];
                 end
                     ID_str = [ID_str,ID_temp,';'];
             end
             
             comp.ObjectPositionEditField.Value = num2str(Po_str);
             comp.ContactPointPositionEditField.Value = num2str(Vo_str);
             comp.TautCableGroupEditField.Value = num2str(ID_str);        

                comp.ForwardKinematicsNumberEditField.Value = num2str(M);
                comp.k3EditField.Value = num2str(M_triangle);
                comp.k4EditField.Value = num2str(M_quadrilateral);
                comp.k5EditField.Value = num2str(M_pentagon);
                comp.k5EditField_2.Value = num2str(M_polygon);          
       
                PlotRobot(zr, ID, Rn, Po);
                %Object projected point
                for j = 1: M
                    plot(comp.UIAxes,Po(j,1),Po(j,2),'bo')
                    hold on;     
                end

                %Object contact point
                for j = 1: M
                    plot(comp.UIAxes,Vo(j,1),Vo(j,2),'ro')
                    hold on;     
                end

                %link
                for i = 1:M
                   plot(comp.UIAxes,[Po(i,1) Vo(i,1)],[Po(i,2) Vo(i,2)],'k:');hold on;
                end  
                        
            else
                comp.ForwardKinematicsNumberEditField.Value = num2str(0);
                comp.k3EditField.Value = num2str(0);
                comp.k4EditField.Value = num2str(0);
                comp.k5EditField.Value = num2str(0);
                comp.k5EditField_2.Value = num2str(0);          
        
            end
        end

        % Button pushed function: ResetEditField
        function ResetEditFieldButtonPushed(comp, event)
            global  Pic_num
             for i=1:Pic_num
                    close(figure(i));
             end
             
             try 
                 delete(allchild(comp.UIAxes));
             end

                comp.RobotFormationEditField.Value = '';
                comp.SheetVerticesEditField.Value = '';
                comp.RobotNumberEditField.Value = '';
                %comp.HeightofHoldingPointsEditField.Value = '';
                comp.ForwardKinematicsNumberEditField.Value = num2str(0);
                comp.k3EditField.Value = num2str(0);
                comp.k4EditField.Value = num2str(0);
                comp.k5EditField.Value = num2str(0);
                comp.k5EditField_2.Value = num2str(0); 
                comp.ObjectPositionEditField.Value = '';
                comp.ContactPointPositionEditField.Value = '';
                comp.TautCableGroupEditField.Value = '';   
        end

        % Value changed function: HeightofHoldingPointsEditField
        function HeightofHoldingPointsEditFieldValueChanged(comp, event)
            global RobotHeight;
            RobotHeight = str2double(comp.HeightofHoldingPointsEditField.Value);
            
        end

        % Value changed function: SheetVerticesEditField
        function SheetVerticesEditFieldValueChanged(comp, event)
            global Vn RobotNumber;
            N = RobotNumber;
            

            %Set sheet shape by text input
            text = comp.SheetVerticesEditField.Value;
            if text(end) == ';'
                text = text(1:end-1);
            end
            rows = strsplit(text, ';');
          
            matrix = zeros(numel(rows), 2);
            
            for i = 1:numel(rows)
                values = strsplit(rows{i}, ',');
                matrix(i, 1) = str2double(values{1});
                matrix(i, 2) = str2double(values{2});
            end

            Vn = matrix;

            %plot in the UIAxes
             plot(comp.UIAxes,Vn(:,1),Vn(:,2),'ro');hold on;   
            for i= 1: N-1
                line(comp.UIAxes,[Vn(i,1) Vn(i+1,1)],[Vn(i,2) Vn(i+1,2)],'Marker','o','color','r');hold on;
            end
                line(comp.UIAxes,[Vn(N,1) Vn(1,1)],[Vn(N,2) Vn(1,2)],'Marker','o','color','r');hold on;
            close(figure(1));  
        end

        % Value changed function: RobotFormationEditField
        function RobotFormationEditFieldValueChanged(comp, event)
            
            global Rn RobotNumber;
            N = RobotNumber;

            %Set robot formation by text input
            text = comp.RobotFormationEditField.Value;
            if text(end) == ';'
                text = text(1:end-1);
            end        
            rows = strsplit(text, ';');
            matrix = zeros(numel(rows), 2);
            
            for i = 1:numel(rows)
                values = strsplit(rows{i}, ',');
                matrix(i, 1) = str2double(values{1});
                matrix(i, 2) = str2double(values{2});
            end

            Rn = matrix;
            
            %plot in the UIAxes
            plot(comp.UIAxes,Rn(:,1),Rn(:,2),'bo');hold on; 

            for i= 1: N-1
                line(comp.UIAxes,[Rn(i,1) Rn(i+1,1)],[Rn(i,2) Rn(i+1,2)],'Marker','o','color','b');hold on;
            end
                line(comp.UIAxes,[Rn(N,1) Rn(1,1)],[Rn(N,2) Rn(1,2)],'Marker','o','color','b');hold on;
            close(figure(1));  
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
   
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [0 -270 976 960];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.HandleVisibility = 'on';

            % Create UIAxes
            comp.UIAxes = uiaxes(comp);
            title(comp.UIAxes, 'Selection of the Sheet Vertices and Robot Formation')
            comp.UIAxes.FontName = 'Times New Roman';
            comp.UIAxes.FontWeight = 'bold';
            comp.UIAxes.XLim = [0 1000];
            comp.UIAxes.YLim = [0 1000];
            comp.UIAxes.XTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'; '700'; '800'; '900'; '1000'};
            comp.UIAxes.YTick = [0 100 200 300 400 500 600 700 800 900 1000];
            comp.UIAxes.YTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'; '700'; '800'; '900'; '1000'};
            comp.UIAxes.ZTick = [];
            comp.UIAxes.FontSize = 14;
            comp.UIAxes.NextPlot = 'add';
            comp.UIAxes.Tag = 'DrawRobot';
            comp.UIAxes.Position = [207 406 535 437];

            % Create InputPanel
            comp.InputPanel = uipanel(comp);
            comp.InputPanel.TitlePosition = 'centertop';
            comp.InputPanel.Title = 'Input';
            comp.InputPanel.FontWeight = 'bold';
            comp.InputPanel.FontSize = 14;
            comp.InputPanel.Position = [17 327 182 544];

            % Create SheetVerticesSelectionButton
            comp.SheetVerticesSelectionButton = uibutton(comp.InputPanel, 'push');
            comp.SheetVerticesSelectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SheetVerticesSelectionButtonPushed, true);
            comp.SheetVerticesSelectionButton.Tag = 'VertexSelect';
            comp.SheetVerticesSelectionButton.BackgroundColor = [1 1 0];
            comp.SheetVerticesSelectionButton.FontWeight = 'bold';
            comp.SheetVerticesSelectionButton.FontColor = [1 0 0];
            comp.SheetVerticesSelectionButton.Position = [6 301 168 30];
            comp.SheetVerticesSelectionButton.Text = 'Sheet Vertices Selection';

            % Create RobotFormationSelectionButton
            comp.RobotFormationSelectionButton = uibutton(comp.InputPanel, 'push');
            comp.RobotFormationSelectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RobotFormationSelectionButtonPushed, true);
            comp.RobotFormationSelectionButton.Tag = 'RobotSelect';
            comp.RobotFormationSelectionButton.BackgroundColor = [1 1 0];
            comp.RobotFormationSelectionButton.FontWeight = 'bold';
            comp.RobotFormationSelectionButton.FontColor = [1 0 0];
            comp.RobotFormationSelectionButton.Position = [10 122 168 30];
            comp.RobotFormationSelectionButton.Text = 'Robot Formation Selection';

            % Create RobotNumberEditFieldLabel
            comp.RobotNumberEditFieldLabel = uilabel(comp.InputPanel);
            comp.RobotNumberEditFieldLabel.HorizontalAlignment = 'center';
            comp.RobotNumberEditFieldLabel.FontWeight = 'bold';
            comp.RobotNumberEditFieldLabel.FontColor = [0 0 1];
            comp.RobotNumberEditFieldLabel.Position = [8 494 166 22];
            comp.RobotNumberEditFieldLabel.Text = 'Robot Number';

            % Create RobotNumberEditField
            comp.RobotNumberEditField = uieditfield(comp.InputPanel, 'text');
            comp.RobotNumberEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RobotNumberEditFieldValueChanged, true);
            comp.RobotNumberEditField.HorizontalAlignment = 'center';
            comp.RobotNumberEditField.FontWeight = 'bold';
            comp.RobotNumberEditField.FontColor = [0 0 1];
            comp.RobotNumberEditField.Position = [29 448 124 44];

            % Create HeightofHoldingPointsEditFieldLabel
            comp.HeightofHoldingPointsEditFieldLabel = uilabel(comp.InputPanel);
            comp.HeightofHoldingPointsEditFieldLabel.HorizontalAlignment = 'center';
            comp.HeightofHoldingPointsEditFieldLabel.FontWeight = 'bold';
            comp.HeightofHoldingPointsEditFieldLabel.FontColor = [0 0 1];
            comp.HeightofHoldingPointsEditFieldLabel.Position = [7 419 166 22];
            comp.HeightofHoldingPointsEditFieldLabel.Text = 'Height of Holding Points';

            % Create HeightofHoldingPointsEditField
            comp.HeightofHoldingPointsEditField = uieditfield(comp.InputPanel, 'text');
            comp.HeightofHoldingPointsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @HeightofHoldingPointsEditFieldValueChanged, true);
            comp.HeightofHoldingPointsEditField.HorizontalAlignment = 'center';
            comp.HeightofHoldingPointsEditField.FontWeight = 'bold';
            comp.HeightofHoldingPointsEditField.FontColor = [0 0 1];
            comp.HeightofHoldingPointsEditField.Position = [28 371 124 42];

            % Create SheetVerticesEditField
            comp.SheetVerticesEditField = uieditfield(comp.InputPanel, 'text');
            comp.SheetVerticesEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SheetVerticesEditFieldValueChanged, true);
            comp.SheetVerticesEditField.Tag = 'VnShow';
            comp.SheetVerticesEditField.FontWeight = 'bold';
            comp.SheetVerticesEditField.Position = [12 208 159 85];

            % Create RobotFormationEditField
            comp.RobotFormationEditField = uieditfield(comp.InputPanel, 'text');
            comp.RobotFormationEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RobotFormationEditFieldValueChanged, true);
            comp.RobotFormationEditField.Tag = 'SheetVertices';
            comp.RobotFormationEditField.HorizontalAlignment = 'center';
            comp.RobotFormationEditField.FontWeight = 'bold';
            comp.RobotFormationEditField.Position = [13 12 159 98];

            % Create OutputPanel
            comp.OutputPanel = uipanel(comp);
            comp.OutputPanel.TitlePosition = 'centertop';
            comp.OutputPanel.Title = 'Output';
            comp.OutputPanel.FontWeight = 'bold';
            comp.OutputPanel.FontSize = 14;
            comp.OutputPanel.Position = [749 327 209 544];

            % Create ForwardKinematicsNumberEditFieldLabel
            comp.ForwardKinematicsNumberEditFieldLabel = uilabel(comp.OutputPanel);
            comp.ForwardKinematicsNumberEditFieldLabel.HorizontalAlignment = 'right';
            comp.ForwardKinematicsNumberEditFieldLabel.FontSize = 14;
            comp.ForwardKinematicsNumberEditFieldLabel.FontWeight = 'bold';
            comp.ForwardKinematicsNumberEditFieldLabel.Position = [3 196 197 22];
            comp.ForwardKinematicsNumberEditFieldLabel.Text = 'Forward Kinematics Number';

            % Create ForwardKinematicsNumberEditField
            comp.ForwardKinematicsNumberEditField = uieditfield(comp.OutputPanel, 'text');
            comp.ForwardKinematicsNumberEditField.Tag = 'SolutionNumber';
            comp.ForwardKinematicsNumberEditField.HorizontalAlignment = 'center';
            comp.ForwardKinematicsNumberEditField.FontSize = 14;
            comp.ForwardKinematicsNumberEditField.Position = [15 151 177 42];

            % Create k3EditFieldLabel
            comp.k3EditFieldLabel = uilabel(comp.OutputPanel);
            comp.k3EditFieldLabel.HorizontalAlignment = 'right';
            comp.k3EditFieldLabel.FontSize = 14;
            comp.k3EditFieldLabel.FontWeight = 'bold';
            comp.k3EditFieldLabel.Position = [28 109 29 22];
            comp.k3EditFieldLabel.Text = 'k=3';

            % Create k3EditField
            comp.k3EditField = uieditfield(comp.OutputPanel, 'text');
            comp.k3EditField.Tag = 'TriangleNumber';
            comp.k3EditField.HorizontalAlignment = 'center';
            comp.k3EditField.FontSize = 14;
            comp.k3EditField.Position = [89 109 100 22];

            % Create k4EditFieldLabel
            comp.k4EditFieldLabel = uilabel(comp.OutputPanel);
            comp.k4EditFieldLabel.HorizontalAlignment = 'right';
            comp.k4EditFieldLabel.FontSize = 14;
            comp.k4EditFieldLabel.FontWeight = 'bold';
            comp.k4EditFieldLabel.Position = [28 76 29 22];
            comp.k4EditFieldLabel.Text = 'k=4';

            % Create k4EditField
            comp.k4EditField = uieditfield(comp.OutputPanel, 'text');
            comp.k4EditField.Tag = 'QuadrilateralNumber';
            comp.k4EditField.HorizontalAlignment = 'center';
            comp.k4EditField.FontSize = 14;
            comp.k4EditField.Position = [89 76 100 22];

            % Create k5EditFieldLabel
            comp.k5EditFieldLabel = uilabel(comp.OutputPanel);
            comp.k5EditFieldLabel.Tag = 'Number5';
            comp.k5EditFieldLabel.HorizontalAlignment = 'right';
            comp.k5EditFieldLabel.FontSize = 14;
            comp.k5EditFieldLabel.FontWeight = 'bold';
            comp.k5EditFieldLabel.Position = [28 43 29 22];
            comp.k5EditFieldLabel.Text = 'k=5';

            % Create k5EditField
            comp.k5EditField = uieditfield(comp.OutputPanel, 'text');
            comp.k5EditField.Tag = 'PentagonNumber';
            comp.k5EditField.HorizontalAlignment = 'center';
            comp.k5EditField.FontSize = 14;
            comp.k5EditField.Position = [89 43 100 22];

            % Create k5EditField_2Label
            comp.k5EditField_2Label = uilabel(comp.OutputPanel);
            comp.k5EditField_2Label.HorizontalAlignment = 'right';
            comp.k5EditField_2Label.FontSize = 14;
            comp.k5EditField_2Label.FontWeight = 'bold';
            comp.k5EditField_2Label.Position = [28 12 29 22];
            comp.k5EditField_2Label.Text = 'k>5';

            % Create k5EditField_2
            comp.k5EditField_2 = uieditfield(comp.OutputPanel, 'text');
            comp.k5EditField_2.Tag = 'PolygonNumber';
            comp.k5EditField_2.HorizontalAlignment = 'center';
            comp.k5EditField_2.FontSize = 14;
            comp.k5EditField_2.Position = [89 12 100 22];

            % Create ObjectPositionEditFieldLabel
            comp.ObjectPositionEditFieldLabel = uilabel(comp.OutputPanel);
            comp.ObjectPositionEditFieldLabel.HorizontalAlignment = 'center';
            comp.ObjectPositionEditFieldLabel.FontSize = 14;
            comp.ObjectPositionEditFieldLabel.FontWeight = 'bold';
            comp.ObjectPositionEditFieldLabel.Position = [14 488 177 22];
            comp.ObjectPositionEditFieldLabel.Text = 'Object Position';

            % Create ObjectPositionEditField
            comp.ObjectPositionEditField = uieditfield(comp.OutputPanel, 'text');
            comp.ObjectPositionEditField.Tag = 'ObjectPosition';
            comp.ObjectPositionEditField.Position = [14 423 177 63];

            % Create ContactPointPositionEditFieldLabel
            comp.ContactPointPositionEditFieldLabel = uilabel(comp.OutputPanel);
            comp.ContactPointPositionEditFieldLabel.Tag = 'ContactPointPositions';
            comp.ContactPointPositionEditFieldLabel.HorizontalAlignment = 'center';
            comp.ContactPointPositionEditFieldLabel.FontSize = 14;
            comp.ContactPointPositionEditFieldLabel.FontWeight = 'bold';
            comp.ContactPointPositionEditFieldLabel.Position = [14 389 177 22];
            comp.ContactPointPositionEditFieldLabel.Text = 'Contact Point Position';

            % Create ContactPointPositionEditField
            comp.ContactPointPositionEditField = uieditfield(comp.OutputPanel, 'text');
            comp.ContactPointPositionEditField.Tag = 'ObjectPosition';
            comp.ContactPointPositionEditField.Position = [14 325 177 63];

            % Create TautCableGroupEditFieldLabel
            comp.TautCableGroupEditFieldLabel = uilabel(comp.OutputPanel);
            comp.TautCableGroupEditFieldLabel.Tag = 'Taut Cable Group';
            comp.TautCableGroupEditFieldLabel.HorizontalAlignment = 'center';
            comp.TautCableGroupEditFieldLabel.FontSize = 14;
            comp.TautCableGroupEditFieldLabel.FontWeight = 'bold';
            comp.TautCableGroupEditFieldLabel.Position = [14 293 177 22];
            comp.TautCableGroupEditFieldLabel.Text = 'Taut Cable Group';

            % Create TautCableGroupEditField
            comp.TautCableGroupEditField = uieditfield(comp.OutputPanel, 'text');
            comp.TautCableGroupEditField.Tag = 'ObjectPosition';
            comp.TautCableGroupEditField.Position = [14 229 177 63];

            % Create ForwardKinematicsCalculationButton
            comp.ForwardKinematicsCalculationButton = uibutton(comp, 'push');
            comp.ForwardKinematicsCalculationButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ForwardKinematicsCalculationButtonPushed, true);
            comp.ForwardKinematicsCalculationButton.Tag = 'FK';
            comp.ForwardKinematicsCalculationButton.BackgroundColor = [1 1 0];
            comp.ForwardKinematicsCalculationButton.FontSize = 18;
            comp.ForwardKinematicsCalculationButton.FontWeight = 'bold';
            comp.ForwardKinematicsCalculationButton.FontColor = [1 0 0];
            comp.ForwardKinematicsCalculationButton.Position = [279 327 182 54];
            comp.ForwardKinematicsCalculationButton.Text = {'Forward Kinematics '; 'Calculation'};

            % Create ResetEditField
            comp.ResetEditField = uibutton(comp, 'push');
            comp.ResetEditField.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ResetEditFieldButtonPushed, true);
            comp.ResetEditField.Tag = 'FK';
            comp.ResetEditField.BackgroundColor = [1 1 0];
            comp.ResetEditField.FontSize = 18;
            comp.ResetEditField.FontWeight = 'bold';
            comp.ResetEditField.FontColor = [1 0 0];
            comp.ResetEditField.Position = [522 327 175 53];
            comp.ResetEditField.Text = 'Reset';

            % Create ForwardKinematicsofMultiRobotSystemwithaDeformableSheetLabel
            comp.ForwardKinematicsofMultiRobotSystemwithaDeformableSheetLabel = uilabel(comp);
            comp.ForwardKinematicsofMultiRobotSystemwithaDeformableSheetLabel.HorizontalAlignment = 'center';
            comp.ForwardKinematicsofMultiRobotSystemwithaDeformableSheetLabel.FontSize = 24;
            comp.ForwardKinematicsofMultiRobotSystemwithaDeformableSheetLabel.FontWeight = 'bold';
            comp.ForwardKinematicsofMultiRobotSystemwithaDeformableSheetLabel.Position = [75 882 799 59];
            comp.ForwardKinematicsofMultiRobotSystemwithaDeformableSheetLabel.Text = {'Forward Kinematics of Multi-Robot System with a Deformable Sheet'; ' '};
        end
    end
end